classdef cobot_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        sendGcodeButton                matlab.ui.control.Button
        IKButton                       matlab.ui.control.Button
        ZEditFieldLabel                matlab.ui.control.Label
        ZEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        InverseKinematicsSwitch        matlab.ui.control.Switch
        InverseKinematicsSwitchLabel   matlab.ui.control.Label
        ForwardKinematicsSwitch        matlab.ui.control.Switch
        ForwardKinematicsSwitchLabel   matlab.ui.control.Label
        EnteranAlphabetEditField       matlab.ui.control.EditField
        EnteranAlphabetEditFieldLabel  matlab.ui.control.Label
        Joint6Slider                   matlab.ui.control.Slider
        Joint5Slider                   matlab.ui.control.Slider
        Joint4Slider                   matlab.ui.control.Slider
        Joint3Slider                   matlab.ui.control.Slider
        Joint2Slider                   matlab.ui.control.Slider
        Joint1Slider                   matlab.ui.control.Slider
        Joint6EditField                matlab.ui.control.NumericEditField
        Joint6EditFieldLabel           matlab.ui.control.Label
        Joint5EditField                matlab.ui.control.NumericEditField
        Joint5EditFieldLabel           matlab.ui.control.Label
        Joint4EditField                matlab.ui.control.NumericEditField
        Joint4EditFieldLabel           matlab.ui.control.Label
        Joint3EditField                matlab.ui.control.NumericEditField
        Joint3EditFieldLabel           matlab.ui.control.Label
        Joint2EditField                matlab.ui.control.NumericEditField
        Joint2EditFieldLabel           matlab.ui.control.Label
        Joint1EditField                matlab.ui.control.NumericEditField
        Joint1EditFieldLabel           matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: Joint1EditField
        function Joint1EditFieldValueChanged(app, event)
            j1 = app.Joint1EditField.Value;
            app.Joint1Slider.Value = j1;
            set_param('cobot_forward_kinematics/Slider Gain', 'Gain',num2str(j1)) 
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            j1 = event.Value;
            app.Joint1EditField.Value = j1;
            set_param('cobot_forward_kinematics/Slider Gain', 'Gain',num2str(j1)) 
        end

        % Value changed function: Joint2EditField
        function Joint2EditFieldValueChanged(app, event)
            j2 = app.Joint2EditField.Value;
            app.Joint2Slider.Value = j2;
            set_param('cobot_forward_kinematics/Slider Gain1', 'Gain',num2str(j2)) 
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            j2 = event.Value;
            app.Joint2EditField.Value = j2;
            set_param('cobot_forward_kinematics/Slider Gain1', 'Gain',num2str(j2)) 
        end

        % Value changed function: Joint3EditField
        function Joint3EditFieldValueChanged(app, event)
            j3 = app.Joint3EditField.Value;
            app.Joint3Slider.Value = j3;
            set_param('cobot_forward_kinematics/Slider Gain2', 'Gain',num2str(j3)) 
        end

        % Value changed function: Joint4EditField
        function Joint4EditFieldValueChanged(app, event)
            j4 = app.Joint4EditField.Value;
            app.Joint4Slider.Value = j4;
            set_param('cobot_forward_kinematics/Slider Gain3', 'Gain',num2str(j4))
        end

        % Value changed function: Joint5EditField
        function Joint5EditFieldValueChanged(app, event)
            j5 = app.Joint5EditField.Value;
            app.Joint5Slider.Value = j5;
            set_param('cobot_forward_kinematics/Slider Gain4', 'Gain',num2str(j5))
        end

        % Value changed function: Joint6EditField
        function Joint6EditFieldValueChanged(app, event)
            j6 = app.Joint6EditField.Value;
            app.Joint6Slider.Value = j6;
            set_param('cobot_forward_kinematics/Slider Gain5', 'Gain',num2str(j6))
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            j3 = event.Value;
            app.Joint3EditField.Value = j3;
            set_param('cobot_forward_kinematics/Slider Gain2', 'Gain',num2str(j3))
        end

        % Value changing function: Joint4Slider
        function Joint4SliderValueChanging(app, event)
            j4 = event.Value;
            app.Joint4EditField.Value = j4;
            set_param('cobot_forward_kinematics/Slider Gain3', 'Gain',num2str(j4))
        end

        % Value changing function: Joint5Slider
        function Joint5SliderValueChanging(app, event)
            j5 = event.Value;
            app.Joint5EditField.Value = j5;
            set_param('cobot_forward_kinematics/Slider Gain4', 'Gain',num2str(j5))
        end

        % Value changing function: Joint6Slider
        function Joint6SliderValueChanging(app, event)
            j6 = event.Value;
            app.Joint6EditField.Value = j6;
            set_param('cobot_forward_kinematics/Slider Gain5', 'Gain',num2str(j6))
        end

        % Value changed function: ForwardKinematicsSwitch
        function ForwardKinematicsSwitchValueChanged(app, event)
            value = app.ForwardKinematicsSwitch.Value;
            model='cobot_forward_kinematics';
            if strcmp(value,'On')
                load_system(model);
                sim(model);
                value = 'Off';
            elseif strcmp(value,'Off')
                set_param(model, 'SimulationCommand', 'stop')
                value = 'On';
            end
        end

        % Value changed function: InverseKinematicsSwitch
        function InverseKinematicsSwitchValueChanged(app, event)
            value = app.InverseKinematicsSwitch.Value;
            model='cobot_inverse_kinematics';
            if strcmp(value,'On')
                load_system(model);
                sim(model);
                value = 'Off';
            elseif strcmp(value,'Off')
                set_param(model, 'SimulationCommand', 'stop')
                value = 'On';
            end
        end

        % Button pushed function: sendGcodeButton
        function sendGcodeButtonPushed(app, event)
            extractingGcodetoMatrix;
            model='cobot_inverse_kinematics';
            for i=1:length(mat)
                x = mat(i,1);
                y = mat(i,2);
                z = mat(i,3);
                set_param([model '/EEPosX'],'Value',num2str(x));
                set_param([model '/EEPosY'],'Value',num2str(y));
                set_param([model '/EEPosZ'],'Value',num2str(z));
                pause(2);

            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            Ts = 0.001;
            robot = importrobot('cobot_urdf.urdf');
            show(robot,Visuals="on",Collisions="off");
        end

        % Value changed function: EnteranAlphabetEditField
        function EnteranAlphabetEditFieldValueChanged(app, event)
            value = app.EnteranAlphabetEditField.Value;
            %Typo created by Adrien Rosselet

%put your mill just touching the surface manually
%set the gcode sender to X0 Y0 Z0
%run the code

%parametres (in mm)
%fprintf(Gcode.Value)
text= value;
lengthLet=40;%minimum half of hightLet
hightLet=60;%letter hight
space=20;%space between letters
depth=-0.3;%engraving depth
up=1;%hight when the tool is mooving
fileID = fopen('gcode.gcode','w');%enter the name of the created file
fprintf(fileID,'%s\n','G21 G90 G17 F90');%Check this header line. Is it ok for your machine?

%program
down=depth;
posAct=0;%X value of actual position
fprintf(fileID,'%s\n','G01 X0 Y0 Z1');%I prefer to make the first move slowly in case...
rapide='G0';
lent='G01';
tourHoraire='G02';
tourAntihoraire='G03';
total_lenght=length(text)*lengthLet
for i=1:length(text)
    switch text(i)
        case 'A'
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',0,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet/2,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',0,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet/4,'Y',hightLet/2,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet/4,'Y',hightLet/2,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet*3/4,'Y',hightLet/2,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet*3/4,'Y',hightLet/2,'Z',up);
            posAct=posAct+lengthLet;
        case 'B'
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',0,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet-hightLet/4,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f %c%.3f\n',tourHoraire,'X',posAct+lengthLet-hightLet/4,'Y',hightLet/2,'I',0,'J',-hightLet/4);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet/2,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet-hightLet/4,'Y',hightLet/2,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f %c%.3f\n',tourHoraire,'X',posAct+lengthLet-hightLet/4,'Y',0,'I',0,'J',-hightLet/4);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',0,'Z',up);
            posAct=posAct+lengthLet;
        case 'C'
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',hightLet*3/4,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',hightLet*3/4,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f %c%.3f\n',tourAntihoraire,'X',posAct+lengthLet-hightLet/4,'Y',hightLet,'I',-hightLet/4,'J',0);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+hightLet/4,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f %c%.3f\n',tourAntihoraire,'X',posAct,'Y',hightLet*3/4,'I',0,'J',-hightLet/4);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet/4,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f %c%.3f\n',tourAntihoraire,'X',posAct+hightLet/4,'Y',0,'I',hightLet/4,'J',0);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet-hightLet/4,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f %c%.3f\n',tourAntihoraire,'X',posAct+lengthLet,'Y',hightLet/4,'I',0,'J',hightLet/4);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',hightLet/4,'Z',up);
            posAct=posAct+lengthLet;
        case 'D'
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',0,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet-hightLet/2,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f %c%.3f\n',tourHoraire,'X',posAct+lengthLet-hightLet/2,'Y',0,'I',0,'J',-hightLet/2);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',0,'Z',up);
            posAct=posAct+lengthLet;
        case 'E'
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',0,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',hightLet,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',hightLet/2,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet/2,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',hightLet/2,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',hightLet/2,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',0,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',0,'Z',up);
            posAct=posAct+lengthLet;
        case 'F'
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',0,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',hightLet,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',hightLet/2,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet/2,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',hightLet/2,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',hightLet/2,'Z',up);
            posAct=posAct+lengthLet;
        case 'G'
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',hightLet*3/4,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',hightLet*3/4,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f %c%.3f\n',tourAntihoraire,'X',posAct+lengthLet-hightLet/4,'Y',hightLet,'I',-hightLet/4,'J',0);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+hightLet/4,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f %c%.3f\n',tourAntihoraire,'X',posAct,'Y',hightLet*3/4,'I',0,'J',-hightLet/4);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet/4,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f %c%.3f\n',tourAntihoraire,'X',posAct+hightLet/4,'Y',0,'I',hightLet/4,'J',0);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet-hightLet/4,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f %c%.3f\n',tourAntihoraire,'X',posAct+lengthLet,'Y',hightLet/4,'I',0,'J',hightLet/4);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',hightLet/2,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet/2,'Y',hightLet/2,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet/2,'Y',hightLet/2,'Z',up);
            posAct=posAct+lengthLet;
        case 'H'
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',0,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',hightLet,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',0,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',hightLet,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',hightLet/2,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet/2,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',hightLet/2,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',hightLet/2,'Z',up);
            posAct=posAct+lengthLet;
        case 'I'
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet/4,'Y',0,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet/4,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet/4,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet/4,'Y',hightLet,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',hightLet,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet/2,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet/2,'Y',hightLet,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',0,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet/2,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet/2,'Y',0,'Z',up);
            posAct=posAct+lengthLet/2;
        case 'J'
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',hightLet/4,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet/4,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f %c%.3f\n',tourAntihoraire,'X',posAct+hightLet/4,'Y',0,'I',+hightLet/4,'J',0);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet-hightLet/4,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f %c%.3f\n',tourAntihoraire,'X',posAct+lengthLet,'Y',hightLet/4,'I',0,'J',+hightLet/4);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',hightLet,'Z',up);
            posAct=posAct+lengthLet;
        case 'K'
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',0,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',hightLet,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',hightLet/2,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet/2,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',hightLet,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',hightLet/2,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet/2,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',0,'Z',up);
            posAct=posAct+lengthLet;
        case 'L'
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',0,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',hightLet,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',0,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',0,'Z',up);
            posAct=posAct+lengthLet;
        case 'M'
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',0,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet/2,'Y',hightLet/2,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',0,'Z',up);
            posAct=posAct+lengthLet;
        case 'N'
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',0,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',hightLet,'Z',up);
            posAct=posAct+lengthLet;
        case 'O'
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',hightLet*3/4,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',hightLet*3/4,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f %c%.3f\n',tourAntihoraire,'X',posAct+lengthLet-hightLet/4,'Y',hightLet,'I',-hightLet/4,'J',0);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+hightLet/4,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f %c%.3f\n',tourAntihoraire,'X',posAct,'Y',hightLet*3/4,'I',0,'J',-hightLet/4);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet/4,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f %c%.3f\n',tourAntihoraire,'X',posAct+hightLet/4,'Y',0,'I',hightLet/4,'J',0);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet-hightLet/4,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f %c%.3f\n',tourAntihoraire,'X',posAct+lengthLet,'Y',hightLet/4,'I',0,'J',hightLet/4);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',hightLet*3/4,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',hightLet*3/4,'Z',up);
            posAct=posAct+lengthLet;
        case 'P'
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',0,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet-hightLet/4,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f %c%.3f\n',tourHoraire,'X',posAct+lengthLet-hightLet/4,'Y',hightLet/2,'I',0,'J',-hightLet/4);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet/2,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',hightLet/2,'Z',up);
            posAct=posAct+lengthLet;
        case 'Q'
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',hightLet*3/4,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',hightLet*3/4,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f %c%.3f\n',tourAntihoraire,'X',posAct+lengthLet-hightLet/4,'Y',hightLet,'I',-hightLet/4,'J',0);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+hightLet/4,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f %c%.3f\n',tourAntihoraire,'X',posAct,'Y',hightLet*3/4,'I',0,'J',-hightLet/4);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet/4,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f %c%.3f\n',tourAntihoraire,'X',posAct+hightLet/4,'Y',0,'I',hightLet/4,'J',0);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet-hightLet/4,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f %c%.3f\n',tourAntihoraire,'X',posAct+lengthLet,'Y',hightLet/4,'I',0,'J',hightLet/4);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',hightLet*3/4,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',hightLet*3/4,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet/2,'Y',hightLet/2,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet/2,'Y',hightLet/2,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',0,'Z',up);
            posAct=posAct+lengthLet;
        case 'R'
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',0,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet-hightLet/4,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f %c%.3f\n',tourHoraire,'X',posAct+lengthLet-hightLet/4,'Y',hightLet/2,'I',0,'J',-hightLet/4);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet/2,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',0,'Z',up);
            posAct=posAct+lengthLet;
        case 'S'
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',hightLet*3/4,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',hightLet*3/4,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f %c%.3f\n',tourAntihoraire,'X',posAct+lengthLet-hightLet/4,'Y',hightLet,'I',-hightLet/4,'J',0);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+hightLet/4,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f %c%.3f\n',tourAntihoraire,'X',posAct+hightLet/4,'Y',hightLet/2,'I',0,'J',-hightLet/4);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet-hightLet/4,'Y',hightLet/2,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f %c%.3f\n',tourHoraire,'X',posAct+lengthLet-hightLet/4,'Y',0,'I',0,'J',-hightLet/4);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+hightLet/4,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f %c%.3f\n',tourHoraire,'X',posAct,'Y',hightLet/4,'I',0,'J',hightLet/4);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',hightLet/4,'Z',up);
            posAct=posAct+lengthLet;
        case 'T'
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet/2,'Y',0,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet/2,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet/2,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet/2,'Y',hightLet,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',hightLet,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',hightLet,'Z',up);
            posAct=posAct+lengthLet;
        case 'U'
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',hightLet,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet/4,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f %c%.3f\n',tourAntihoraire,'X',posAct+hightLet/4,'Y',0,'I',hightLet/4,'J',0);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet-hightLet/4,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f %c%.3f\n',tourAntihoraire,'X',posAct+lengthLet,'Y',hightLet/4,'I',0,'J',hightLet/4);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',hightLet,'Z',up);
            posAct=posAct+lengthLet;
        case 'V'
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',hightLet,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet/2,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',hightLet,'Z',up);
            posAct=posAct+lengthLet;
        case 'W'
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',hightLet,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet/4,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet/2,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet*3/4,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',hightLet,'Z',up);
            posAct=posAct+lengthLet;
        case 'X'
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',hightLet,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',0,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',0,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',hightLet,'Z',up);
            posAct=posAct+lengthLet;
        case 'Y'
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet/2,'Y',0,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet/2,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet/2,'Y',hightLet/2,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',hightLet,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet/2,'Y',hightLet/2,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet/2,'Y',hightLet/2,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',hightLet,'Z',up);
            posAct=posAct+lengthLet;
        case 'Z'
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct+lengthLet,'Y',0,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct+lengthLet,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',hightLet,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',hightLet,'Z',up);
            posAct=posAct+lengthLet;
        case '.'
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',0,'Z',up);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',lent,'X',posAct,'Y',0,'Z',down);
            fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',posAct,'Y',0,'Z',up);
        case ' '
            posAct=posAct+lengthLet;
    end
    posAct=posAct+space;
end
fprintf(fileID,'%s %c%.3f %c%.3f %c%.3f\n',rapide,'X',0,'Y',0,'Z',up);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Robot Figure')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Joint1EditFieldLabel = uilabel(app.UIFigure);
            app.Joint1EditFieldLabel.Text = 'Joint1';
            app.Joint1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Joint1EditField.ValueChangedFcn = createCallbackFcn(app, @Joint1EditFieldValueChanged, true);
            app.Joint2EditFieldLabel = uilabel(app.UIFigure);
            app.Joint2EditFieldLabel.Text = 'Joint2';
            app.Joint2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Joint2EditField.ValueChangedFcn = createCallbackFcn(app, @Joint2EditFieldValueChanged, true);
            app.Joint3EditFieldLabel = uilabel(app.UIFigure);
            app.Joint3EditFieldLabel.Text = 'Joint3';
            app.Joint3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Joint3EditField.ValueChangedFcn = createCallbackFcn(app, @Joint3EditFieldValueChanged, true);
            app.Joint4EditFieldLabel = uilabel(app.UIFigure);
            app.Joint4EditFieldLabel.Text = 'Joint4';
            app.Joint4EditField = uieditfield(app.UIFigure, 'numeric');
            app.Joint4EditField.ValueChangedFcn = createCallbackFcn(app, @Joint4EditFieldValueChanged, true);
            app.Joint5EditFieldLabel = uilabel(app.UIFigure);
            app.Joint5EditFieldLabel.Text = 'Joint5';
            app.Joint5EditField = uieditfield(app.UIFigure, 'numeric');
            app.Joint5EditField.ValueChangedFcn = createCallbackFcn(app, @Joint5EditFieldValueChanged, true);
            app.Joint6EditFieldLabel = uilabel(app.UIFigure);
            app.Joint6EditFieldLabel.Text = 'Joint6';
            app.Joint6EditField = uieditfield(app.UIFigure, 'numeric');
            app.Joint6EditField.ValueChangedFcn = createCallbackFcn(app, @Joint6EditFieldValueChanged, true);
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-165 165];
            app.Joint1Slider.MajorTicks = [-165 -135 -90 -45 0 45 90 135 165];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint1Slider.MinorTicks = [];
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [-165 165];
            app.Joint2Slider.MajorTicks = [-165 -135 -90 -45 0 45 90 135 165];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint2Slider.MinorTicks = [];
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [-165 165];
            app.Joint3Slider.MajorTicks = [-165 -135 -90 -45 0 45 90 135 165];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.Joint3Slider.MinorTicks = [];
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.Limits = [-165 165];
            app.Joint4Slider.MajorTicks = [-165 -135 -90 -45 0 45 90 135 165];
            app.Joint4Slider.ValueChangingFcn = createCallbackFcn(app, @Joint4SliderValueChanging, true);
            app.Joint4Slider.MinorTicks = [];
            app.Joint5Slider = uislider(app.UIFigure);
            app.Joint5Slider.Limits = [-165 165];
            app.Joint5Slider.MajorTicks = [-165 -135 -90 -45 0 45 90 135 165];
            app.Joint5Slider.ValueChangingFcn = createCallbackFcn(app, @Joint5SliderValueChanging, true);
            app.Joint5Slider.MinorTicks = [];
            app.Joint6Slider = uislider(app.UIFigure);
            app.Joint6Slider.Limits = [-175 175];
            app.Joint6Slider.MajorTicks = [-175 -135 -90 -45 0 45 90 135 175];
            app.Joint6Slider.ValueChangingFcn = createCallbackFcn(app, @Joint6SliderValueChanging, true);
            app.Joint6Slider.MinorTicks = [];
            app.EnteranAlphabetEditFieldLabel = uilabel(app.UIFigure);
            app.EnteranAlphabetEditFieldLabel.Text = 'Enter an Alphabet';
            app.EnteranAlphabetEditField = uieditfield(app.UIFigure, 'text');
            app.EnteranAlphabetEditField.ValueChangedFcn = createCallbackFcn(app, @EnteranAlphabetEditFieldValueChanged, true);
            app.ForwardKinematicsSwitchLabel = uilabel(app.UIFigure);
            app.ForwardKinematicsSwitchLabel.Text = 'Forward Kinematics';
            app.ForwardKinematicsSwitch = uiswitch(app.UIFigure, 'slider');
            app.ForwardKinematicsSwitch.ValueChangedFcn = createCallbackFcn(app, @ForwardKinematicsSwitchValueChanged, true);
            app.InverseKinematicsSwitchLabel = uilabel(app.UIFigure);
            app.InverseKinematicsSwitchLabel.Text = 'Inverse Kinematics';
            app.InverseKinematicsSwitch = uiswitch(app.UIFigure, 'slider');
            app.InverseKinematicsSwitch.ValueChangedFcn = createCallbackFcn(app, @InverseKinematicsSwitchValueChanged, true);
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.IKButton = uibutton(app.UIFigure, 'push');
            app.IKButton.Text = 'IK';
            app.sendGcodeButton = uibutton(app.UIFigure, 'push');
            app.sendGcodeButton.ButtonPushedFcn = createCallbackFcn(app, @sendGcodeButtonPushed, true);
            app.sendGcodeButton.Text = 'sendGcode';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cobot_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end